classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ContinueFromCheckBox            matlab.ui.control.CheckBox
        RemoveDirectoryButton           matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        Button                          matlab.ui.control.Button
        SetConfigurationsLabel          matlab.ui.control.Label
        SetDataDirectoriesLabel         matlab.ui.control.Label
        AddDirectoryButton              matlab.ui.control.Button
        DictTable                       matlab.ui.control.Table
        UsecustomspikecuttingCheckBox   matlab.ui.control.CheckBox
        TimeStampsDirEditField          matlab.ui.control.EditField
        TimeStampsDirEditFieldLabel     matlab.ui.control.Label
        BrowseTimestampButton           matlab.ui.control.Button
        StatusTextArea                  matlab.ui.control.TextArea
        StatusTextAreaLabel             matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        BrowseOutputButton              matlab.ui.control.Button
        OutputDirEditField              matlab.ui.control.EditField
        OutputDirEditFieldLabel         matlab.ui.control.Label
        BrowseRecordingButton           matlab.ui.control.Button
        RecordingDirEditField           matlab.ui.control.EditField
        RecordingDirEditFieldLabel      matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        SIMULATEDEditField              matlab.ui.control.EditField
        SIMULATEDEditFieldLabel         matlab.ui.control.Label
        NUM_OF_UNITSEditField           matlab.ui.control.EditField
        NUM_OF_UNITSEditFieldLabel      matlab.ui.control.Label
        UPDATE_GRADESEditField          matlab.ui.control.EditField
        UPDATE_GRADESEditFieldLabel     matlab.ui.control.Label
        ONLY_NEURONSEditField           matlab.ui.control.EditField
        ONLY_NEURONSEditFieldLabel      matlab.ui.control.Label
        ONLY_OVERLAP_WITH_NEURONSEditField  matlab.ui.control.EditField
        ONLY_OVERLAP_WITH_NEURONSEditFieldLabel  matlab.ui.control.Label
        OVERLAPEditField                matlab.ui.control.EditField
        OVERLAPEditFieldLabel           matlab.ui.control.Label
        UPDATE_CLASSIFICATIONEditField  matlab.ui.control.EditField
        UPDATE_CLASSIFICATIONEditFieldLabel  matlab.ui.control.Label
        DEBUGEditField                  matlab.ui.control.EditField
        DEBUGEditFieldLabel             matlab.ui.control.Label
        MAX_EUC_DISTEditField           matlab.ui.control.EditField
        MAX_EUC_DISTEditFieldLabel      matlab.ui.control.Label
        ConfigFilePathEditField         matlab.ui.control.EditField
        ConfigFilePathEditFieldLabel    matlab.ui.control.Label
    end


    properties (Access = private)

        % User spikesort_config.m path
        OriginalConfigFile char

        % Read/write to path
        ConfigFile char
        Config struct

        RecordingsDir char
        TimestampsDir char
        OutputDir char

        DictList cell = {};

        % List of parameters 
        % Need to add further ones below

        KeepList = {
            'DEBUG',
            'MAX_EUC_DIST',
            'UPDATE_CLASSIFICATION',
            'OVERLAP',
            'ONLY_OVERLAP_WITH_NEURONS',
            'ONLY_NEURONS',
            'UPDATE_GRADES',
            'NUM_OF_UNITS',
            'SIMULATED',

            };


        % Type classification for checks (work in progress)
        BoolList = {
            'DEBUG',
            'UPDATE_CLASSIFICATION',
            'ONLY_OVERLAP_WITH_NEURONS',
            'ONLY_NEURONS',
            'UPDATE_GRADES',
            'SIMULATED'
            };
        IntList  = {'NUM_OF_UNITS'};
        NumList  = {'MAX_EUC_DIST', 'OVERLAP'};
        StrList  = {};


    end

    methods (Access = private)
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Note - might be different (based on github)
            addpath(genpath(fullfile(pwd, 'cluster_neuronspikes')));
        end

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile({'*.m;*.mat','Config Files (*.m, *.mat)'}, ...
                'Select spikesort_config file');

            if isequal(file,0), return; end

            app.ConfigFile = fullfile(path, file);
            app.ConfigFilePathEditField.Value = app.ConfigFile;

            [~, ~, ext] = fileparts(file);


            % Load struct
            switch lower(ext)
                case '.mat'
                    data = load(app.ConfigFile);
                    if isfield(data,'config') && isstruct(data.config)
                        cfg = data.config;
                    else
                        f = fieldnames(data);
                        cfg = data.(f{1});
                    end

                case '.m'
                    cfgFolder = fileparts(app.ConfigFile);
                    addpath(cfgFolder);
                    cfg = spikesort_config();
                    rmpath(cfgFolder);

                otherwise
                    uialert(app.UIFigure, 'Unsupported file type','Error');
                    return;
            end

            % Store struct
            app.Config = cfg;

            % Fill in fields from KeepList
            for i = 1:numel(app.KeepList)
                fn   = app.KeepList{i};  
                comp = fn + "EditField";         
                if ~isprop(app, comp) || ~isfield(cfg, fn)
                    continue;
                end

                val = cfg.(fn);
                % Convert to string 
                if isstring(val) || ischar(val)
                    s = string(val);
                elseif islogical(val)
                    s = string(val);
                elseif isnumeric(val)
                    s = string(val);
                else
                    s = string(val);  
                end

                app.(comp).Value = s;
            end

            uialert(app.UIFigure, 'Configuration loaded.', 'Success');

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)

            % Check for loaded config
            if isempty(app.Config) || ~isstruct(app.Config)
                uialert(app.UIFigure, 'No config loaded to save changes to.', 'Error');
                return;
            end

            % Update config fields using UI
            for i = 1:numel(app.KeepList)
                fn   = app.KeepList{i};
                comp = fn + "EditField";
                if ~isprop(app, comp)
                    continue;
                end

                % User input
                newVal = app.(comp).Value;  

                
                if newVal == ""
                    continue;
                end

                % Convert string back to data type
                if ismember(fn, app.BoolList)
                    % Boolean
                    if strcmpi(newVal, "true")
                        val = true;
                    elseif strcmpi(newVal, "false")
                        val = false;
                    else
                        uialert(app.UIFigure, sprintf('"%s" must be true or false.', fn), 'Validation Error');
                        return;
                    end

                elseif ismember(fn, app.IntList)
                    % Int
                    v = str2double(newVal);
                    if isnan(v) || v~=floor(v)
                        uialert(app.UIFigure, sprintf('"%s" must be an integer.', fn), 'Validation Error');
                        return;
                    end
                    val = v;

                elseif ismember(fn, app.NumList)
                    % Float
                    v = str2double(newVal);
                    if isnan(v)
                        uialert(app.UIFigure, sprintf('"%s" must be numeric.', fn), 'Validation Error');
                        return;
                    end
                    val = v;

                else
                    val = char(newVal);
                end

                % d) Store in config struct
                app.Config.(fn) = val;
            end

            % Prompt user where to save the file
            [file, folder] = uiputfile('*.mat', 'Save configuration as...');
            if isequal(file,0)
                return;
            end
            savePath = fullfile(folder, file);

            % Save struct
            config = app.Config;
            try
                save(savePath, 'config');
                uialert(app.UIFigure, 'Configuration saved to .mat file.', 'Success');
            catch ME
                uialert(app.UIFigure, "Failed to save: " + ME.message, 'Error');
            end

        end

        % Button pushed function: BrowseRecordingButton
        function BrowseRecordingButtonPushed(app, event)
            directory = uigetdir('.', 'Select Recordings Folder');
            if isequal(directory,0), return; end

            app.RecordingsDir = directory;
            app.RecordingDirEditField.Value = directory;

            % Check if directory is empty
            d = dir(fullfile(directory, '*.*'));
            if isempty(d) || numel(d) <= 2
                options = uiconfirm(app.UIFigure, ...
                    'The recordings folder appears to be empty. Do you want to continue?', ...
                    'Empty Folder Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                if strcmp(options, 'Cancel')
                    return;
                end
            end
        end

        % Button pushed function: BrowseOutputButton
        function BrowseOutputButtonPushed(app, event)
            directory = uigetdir('.', 'Select Output Folder');
            if isequal(directory,0), return; end

            app.OutputDir = directory;
            app.OutputDirEditField.Value = directory;

            % Check if outputs folder is not empty
            if ~isempty(dir(fullfile(app.OutputDir, '*.*'))) && numel(dir(fullfile(app.OutputDir, '*.*'))) > 2
                options = uiconfirm(app.UIFigure, ...
                    'The output folder is not empty. Existing files may be overwritten. Do you want to continue?', ...
                    'Overwrite Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                if strcmp(options, 'Cancel')
                    return;
                end
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)

            if isempty(app.TimestampsDir) || ~isfolder(app.TimestampsDir)
                uialert(app.UIFigure, 'Please select a valid timestamps directory.', 'Error');
                return;
            end

            if isempty(app.RecordingsDir) || ~isfolder(app.RecordingsDir)
                uialert(app.UIFigure, 'Please select a valid recordings directory.', 'Error');
                return;
            end

            if isempty(app.OutputDir) || ~isfolder(app.OutputDir)
                uialert(app.UIFigure, 'Please select a valid output directory.', 'Error');
                return;
            end

            % Check timestamps and recordings for same length
            % Check timestamps.mat
            tsFile = fullfile(app.TimestampsDir, 'timestamps.mat');
            if ~isfile(tsFile)
                uialert(app.UIFigure, 'timestamps.mat not found in the selected folder.', 'Error');
                return;
            end
            tsData = load(tsFile);
            tsFields = fieldnames(tsData);
            timestamps = tsData.(tsFields{1});  % assume the first variable
            szTs = size(timestamps);
            if numel(szTs)~=2 || szTs(1)~=1
                uialert(app.UIFigure, 'timestamps must be a 1×N array.', 'Error');
                return;
            end
            N = szTs(2);
            
            % Check each channel file 
            chanFiles = dir(fullfile(app.RecordingsDir, 'c*.mat'));
            if isempty(chanFiles)
                uialert(app.UIFigure, 'No channel files (c*.mat) found in recordings folder.', 'Error');
                return;
            end
            
            for k = 1:numel(chanFiles)
                fp = fullfile(chanFiles(k).folder, chanFiles(k).name);
                C = load(fp);
                f = fieldnames(C);
                chanData = C.(f{1});       % assume only one variable per file
                szC = size(chanData);
                if numel(szC)~=2 || szC(1)~=1 || szC(2)~=N
                    uialert(app.UIFigure, sprintf( ...
                        'Channel file "%s" must be 1×%d but is %dx%d.', ...
                        chanFiles(k).name, N, szC(1), szC(2)), 'Error');
                    return;
                end
            end
            
            % All lengths match then 
            uialert(app.UIFigure, sprintf('Timestamps and %d channel files all match length %d.', ...
                numel(chanFiles), N),'Info');


            % app.StatusTextArea.Value = "Beginning the analysis.";
            % drawnow;

            % Fix getting from config
            zScores   = [3 4 5 6 7 8 9];

            % Parameter in app - is data simulated or not (ground truth)
            gtDir     = 'D:\spike_gen_data\Recording By Channel Ground Truth';

            directory_list = app.DictTable.Data(:, 2);
            
            % Run algorithm for each z-score
            % Fix getting values from config

            for j=1:length(zScores)
                close all;
                clc;
                scale_factor = -1;
                dir_with_channel_recordings = app.RecordingsDir;
                min_z_score = zScores(j);
                min_threshold = 20;
                num_dps = 60;
                timestamps_dir = app.TimestampsDir;
                precomputed_dir_current = app.OutputDir;


                what_is_precomputed = [""];
                % what_is_precomputed = ["mean_and_std","z_score","dictionaries min_z_score "+string(min_z_score)+" num_dps 60","spike_windows min_z_score "+string(min_z_score)+" num dps 60","spikes_per_channel min_z_score " + string(min_z_score)];

                if ~app.UsecustomspikecuttingCheckBox.Value
                    what_is_precomputed = run_entire_clustering_algorithm_ver_2(scale_factor,dir_with_channel_recordings,min_z_score,num_dps,timestamps_dir,precomputed_dir_current,what_is_precomputed,min_threshold);
                    % what_is_precomputed = ["z_score","mean_and_std"];
                else
                    dictionaries_dir = directory_list(j);
                    what_is_precomputed = custom_spike_algorithm_run_entire_clustering_algorithm_ver_2(scale_factor,dir_with_channel_recordings,min_z_score,num_dps,timestamps_dir,precomputed_dir_current,what_is_precomputed,min_threshold, dictionaries_dir);
                end
            end
           
        end

        % Button pushed function: BrowseTimestampButton
        function BrowseTimestampButtonPushed(app, event)
            directory = uigetdir('.', 'Select Timestamps Folder');
            if isequal(directory,0), return; end

            app.TimestampsDir = directory;
            app.TimeStampsDirEditField.Value = directory;

            % Check if directory is empty
            if isempty(dir(fullfile(app.TimestampsDir, '*.*'))) || numel(dir(fullfile(app.TimestampsDir, '*.*'))) <= 2
                options = uiconfirm(app.UIFigure, ...
                    'The timestamps folder appears to be empty. Do you want to continue?', ...
                    'Empty Folder Warning', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Cancel', ...
                    'CancelOption', 'Cancel');
                if strcmp(options, 'Cancel')
                    return;
                end
            end
        end

        % Value changed function: UsecustomspikecuttingCheckBox
        function UsecustomspikecuttingCheckBoxValueChanged(app, event)
            value = app.UsecustomspikecuttingCheckBox.Value;

            % Call another app that opens in background

            if value
                app.DictTable.Visible = "on";
                app.AddDirectoryButton.Visible = "on";
                app.RemoveDirectoryButton.Visible = "on";
            else
                app.DictTable.Visible = "off";
                app.AddDirectoryButton.Visible = "off";
                app.RemoveDirectoryButton.Visible = "off";
            end

        end

        % Button pushed function: AddDirectoryButton
        function AddDirectoryButtonPushed(app, event)
            % 
            % folder = uigetdir('.', 'Select a dictionary folder');
            % if isequal(folder, 0)
            %     return;
            % end
            % 
            % [parent, base] = fileparts(folder);
            % 
            % % Find prefix 
            % tok = regexp(base, '^(.*?)(?=\d)','tokens','once');
            % prefix = tok{1};
            % if isempty(prefix)
            %     prefix = base;
            % end
            % 
            % % Find similar files that share prefix
            % d      = dir(parent);
            % isdir  = [d.isdir] & ~ismember({d.name}, {'.','..'});
            % names  = {d(isdir).name};
            % similarDirs = names(startsWith(names, prefix));
            % 
            % % Build paths, don't include ones already in table
            % possibleAdditions = {};
            % for k = 1:numel(similarDirs)
            %     fp = fullfile(parent, similarDirs{k});
            %     if ~any(strcmp(app.DictList, fp))
            %         possibleAdditions{end+1} = similarDirs{k};  
            %     end
            % end
            % 
            % % Add the one selected if not already in table
            % toAdd = {};
            % if ~any(strcmp(app.DictList, folder))
            %     toAdd{end+1} = base;
            % end
            % 
            % % Prompt for adding new directories
            % extras = setdiff(possibleAdditions, {base}, 'stable');
            % if ~isempty(extras)
            %     listStr = strjoin(extras, newline);
            %     choice = uiconfirm(app.UIFigure, ...
            %         sprintf("Found %d additional folders:\n%s\n\nAdd all of these too?", ...
            %                 numel(extras), listStr), ...
            %         'Add similar directories?', ...
            %         'Options', {'Yes','No'}, ...
            %         'DefaultOption','Yes');
            %     if strcmp(choice, 'Yes')
            %         toAdd = [toAdd, extras];
            %     end
            % end
            % 
            % % Add each selected directory to DictList
            % for k = 1:numel(toAdd)
            %     fp = fullfile(parent, toAdd{k});
            %     app.DictList{end+1,1} = fp;
            % end
            % 
            % % Update table
            % n = numel(app.DictList);
            % data = cell(n,2);
            % for i = 1:n
            %     data{i,1} = i;
            %     data{i,2} = app.DictList{i};
            % end
            % app.DictTable.Data = data;


            % NO FLOAT Z SCORE THINGS YET
            % % 1) User picks one directory
            % folder = uigetdir('.', 'Select a dictionary folder');
            % if isequal(folder, 0)
            %     return;
            % end
            % [parent, base] = fileparts(folder);
            % 
            % % 2) Regex pattern allowing floats like 4 or 4.8
            % %    ^dictionaries␣min_z_score␣<float>␣num_dps␣<int>$
            % %
            % %    <float> = one+ digits, optional “.digits”
            % pat = ['^dictionaries min_z_score ', ...
            %        '([0-9]+(?:\.[0-9]+)?)', ...  % capture float
            %        '\s+num_dps\s+([0-9]+)$'];    % capture integer
            % 
            % % 3) Validate the selected folder
            % if isempty(regexp(base, pat, 'once'))
            %     uialert(app.UIFigure, ...
            %         'Folder name must match "dictionaries min_z_score <num> num_dps <num>".', ...
            %         'Invalid Folder');
            %     return;
            % end
            % 
            % % 4) List all sibling directories
            % d     = dir(parent);
            % dirs  = d([d.isdir] & ~ismember({d.name},{'.','..'}));
            % names = {dirs.name};
            % 
            % % 5) Collect *new* siblings matching the pattern (excluding chosen)
            % similar = {};
            % for k = 1:numel(names)
            %     nm = names{k};
            %     if strcmp(nm, base)
            %         continue;
            %     end
            %     if ~isempty(regexp(nm, pat, 'once'))
            %         fp = fullfile(parent, nm);
            %         if ~any(strcmp(app.DictList, fp))
            %             similar{end+1} = fp;  %#ok<AGROW>
            %         end
            %     end
            % end
            % 
            % % 6) Always include the single folder they chose
            % toAdd = {};
            % if ~any(strcmp(app.DictList, folder))
            %     toAdd{end+1} = folder;
            % end
            % 
            % % 7) If there are extras, prompt with full paths
            % if ~isempty(similar)
            %     listStr = strjoin(similar, '\n');
            %     choice = uiconfirm(app.UIFigure, ...
            %         sprintf("Found %d additional folders:\n\n%s\n\nAdd them too?", ...
            %                 numel(similar), listStr), ...
            %         'Add Similar Folders?', ...
            %         'Options', {'Yes','No'}, ...
            %         'DefaultOption', 'Yes');
            %     if strcmp(choice, 'Yes')
            %         toAdd = [toAdd, similar];
            %     end
            % end
            % 
            % % 8) Append each new folder to the internal list
            % for k = 1:numel(toAdd)
            %     app.DictList{end+1,1} = toAdd{k};
            % end
            % 
            % % 9) Rebuild and update the UITable
            % n    = numel(app.DictList);
            % data = cell(n,2);
            % for i = 1:n
            %     data{i,1} = i;
            %     data{i,2} = app.DictList{i};
            % end
            % app.DictTable.Data = data;

            
            folder = uigetdir('.', 'Select a dictionary folder');
            if isequal(folder, 0), return; end
        
            [parent, base] = fileparts(folder);
        
            % Pattern for folder names
            pat = '^dictionaries min_z_score [0-9]+(?:\.[0-9]+)?\s+num_dps\s+[0-9]+$';
            if isempty(regexp(base, pat, 'once'))
                uialert(app.UIFigure, 'Folder must be "dictionaries min_z_score <num> num_dps <int>".', 'Invalid Folder');
                return;
            end
        
            % Similar folders
            d      = dir(parent);
            dirs   = d([d.isdir] & ~ismember({d.name},{'.','..'}));
            names  = {dirs.name};
            similar = {};
            for k = 1:numel(names)
                nm = names{k};
                if strcmp(nm, base), continue; end
                if ~isempty(regexp(nm, pat, 'once'))
                    fp = fullfile(parent, nm);
                    if ~any(strcmp(app.DictList, fp))
                        similar{end+1} = fp;
                    end
                end
            end
        
            toConsider = {folder};
     
            if ~isempty(similar)
                listStr = strjoin(similar, newline);
                choice = uiconfirm(app.UIFigure, ...
                    sprintf("Found %d additional folders:\n%s\n\nAdd them too?", ...
                            numel(similar), listStr), ...
                    'Add Similar Folders?', ...
                    'Options',{'Yes','No'}, ...
                    'DefaultOption','Yes');
                if strcmp(choice,'Yes')
                    toConsider = [toConsider, similar];
                end
            end
        
            % Check each directory before appending
            validFolders = {};
            for k = 1:numel(toConsider)
                fp = toConsider{k};
        
                try
                    % Check sorted_spike_windows.mat
                    swFile = dir(fullfile(fp,'t1 sorted_spike_windows*.mat'));
                    assert(~isempty(swFile), 'Missing sorted_spike_windows file.');
                    S = load(fullfile(swFile(1).folder,swFile(1).name));
                    fn = fieldnames(S); swMap = S.(fn{1});
                    data = swMap('t1'); sz = size(data);
                    assert(numel(sz)>=2 && sz(2)==4, 'sorted_spike_windows size mismatch.');
                    numSpikes = sz(1);
        
                    % Check timing_tetrode_dictionary.mat
                    ttFile = dir(fullfile(fp,'t1 timing_tetrode_dictionary*.mat'));
                    assert(~isempty(ttFile), 'Missing timing_tetrode_dictionary file.');
                    T = load(fullfile(ttFile(1).folder,ttFile(1).name));
                    fn = fieldnames(T); ttMap = T.(fn{1});
                    data2 = ttMap('t1'); sz2 = size(data2);
                    assert(numel(sz2)>=2 && sz2(1)==numSpikes, 'Timing dictionary spikes mismatch.');
                    numDpts = sz2(2);
        
                    % Check spiking_channel_tetrode_dictionary.mat
                    scFile = dir(fullfile(fp,'t1 spiking_channel_tetrode_dictionary*.mat'));
                    assert(~isempty(scFile), 'Missing spiking_channel_tetrode_dictionary file.');
                    C = load(fullfile(scFile(1).folder,scFile(1).name));
                    fn = fieldnames(C); scMap = C.(fn{1});
                    data3 = scMap('t1'); sz3 = size(data3);
                    assert(isequal(sz3, [1 numSpikes]), 'Spiking channel size mismatch.');
        
                    % % CHeck spike_tetrode_dictionary.mat
                    % sdFile = dir(fullfile(fp,'t1 spike_tetrode_dictionary*.mat'));
                    % assert(~isempty(sdFile), 'Missing spike_tetrode_dictionary file.');
                    % D = load(fullfile(sdFile(1).folder,sdFile(1).name));
                    % fn = fieldnames(D); sdMap = D.(fn{1});
                    % data4 = sdMap('t1'); sz4 = size(data4);
                    % assert(isequal(sz4, [4 numSpikes numDpts]), 'Spike dictionary size mismatch.');
        
                    % Check spike_tetrode_dictionaries_samples_format.mat 
                    sfFile = dir(fullfile(fp,'t1 spike_tetrode_dictionaries_samples_format*.mat'));
                    assert(~isempty(sfFile), 'Missing samples_format file.');
                    F = load(fullfile(sfFile(1).folder,sfFile(1).name));
                    fn = fieldnames(F); sfMap = F.(fn{1});
                    data5 = sfMap('t1'); sz5 = size(data5);
                    assert(isequal(sz5, [numDpts 4 numSpikes]), 'Samples-format size mismatch.');
        
                    % All checks passed
                    validFolders{end+1} = fp; 
        
                catch ME
                    uialert(app.UIFigure, sprintf('Folder "%s" failed: %s', fp, ME.message), ...
                           'Validation Error');
                end
            end
        
            % Append valid directories
            for k = 1:numel(validFolders)
                app.DictList{end+1,1} = validFolders{k};
            end
        
            % Create UITable
            n = numel(app.DictList);
            data = cell(n,2);
            for i = 1:n
                data{i,1} = i;
                data{i,2} = app.DictList{i};
            end
            app.DictTable.Data = data;
        end

        % Button pushed function: RemoveDirectoryButton
        function RemoveDirectoryButtonPushed(app, event)
            % Get rows that are selected
            sel = app.DictTable.Selection;
            if isempty(sel)
                uialert(app.UIFigure, 'No rows selected to remove.','Info');
                return;
            end
            rows = unique(sel(:,1));
        
            % Remove from DictList from the bottom
            for r = flip(rows')
                app.DictList(r) = [];
            end
        
            % Populate table again
            n = numel(app.DictList);
            data = cell(n,2);
            for i = 1:n
                data{i,1} = i;
                data{i,2} = app.DictList{i};
            end
            app.DictTable.Data = data;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.ConfigFilePathEditFieldLabel.Text = 'ConfigFilePath';
            app.ConfigFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.MAX_EUC_DISTEditFieldLabel = uilabel(app.UIFigure);
            app.MAX_EUC_DISTEditFieldLabel.Text = 'MAX_EUC_DIST';
            app.MAX_EUC_DISTEditField = uieditfield(app.UIFigure, 'text');
            app.DEBUGEditFieldLabel = uilabel(app.UIFigure);
            app.DEBUGEditFieldLabel.Text = 'DEBUG';
            app.DEBUGEditField = uieditfield(app.UIFigure, 'text');
            app.DEBUGEditField.Tooltip = {''};
            app.UPDATE_CLASSIFICATIONEditFieldLabel = uilabel(app.UIFigure);
            app.UPDATE_CLASSIFICATIONEditFieldLabel.Text = 'UPDATE_CLASSIFICATION';
            app.UPDATE_CLASSIFICATIONEditField = uieditfield(app.UIFigure, 'text');
            app.OVERLAPEditFieldLabel = uilabel(app.UIFigure);
            app.OVERLAPEditFieldLabel.Text = 'OVERLAP';
            app.OVERLAPEditField = uieditfield(app.UIFigure, 'text');
            app.ONLY_OVERLAP_WITH_NEURONSEditFieldLabel = uilabel(app.UIFigure);
            app.ONLY_OVERLAP_WITH_NEURONSEditFieldLabel.Text = 'ONLY_OVERLAP_WITH_NEURONS';
            app.ONLY_OVERLAP_WITH_NEURONSEditField = uieditfield(app.UIFigure, 'text');
            app.ONLY_NEURONSEditFieldLabel = uilabel(app.UIFigure);
            app.ONLY_NEURONSEditFieldLabel.Text = 'ONLY_NEURONS';
            app.ONLY_NEURONSEditField = uieditfield(app.UIFigure, 'text');
            app.UPDATE_GRADESEditFieldLabel = uilabel(app.UIFigure);
            app.UPDATE_GRADESEditFieldLabel.Text = 'UPDATE_GRADES';
            app.UPDATE_GRADESEditField = uieditfield(app.UIFigure, 'text');
            app.NUM_OF_UNITSEditFieldLabel = uilabel(app.UIFigure);
            app.NUM_OF_UNITSEditFieldLabel.Text = 'NUM_OF_UNITS';
            app.NUM_OF_UNITSEditField = uieditfield(app.UIFigure, 'text');
            app.SIMULATEDEditFieldLabel = uilabel(app.UIFigure);
            app.SIMULATEDEditFieldLabel.Text = 'SIMULATED';
            app.SIMULATEDEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'BrowseButton';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'SaveButton';
            app.RecordingDirEditFieldLabel = uilabel(app.UIFigure);
            app.RecordingDirEditFieldLabel.Text = 'RecordingDir';
            app.RecordingDirEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseRecordingButton = uibutton(app.UIFigure, 'push');
            app.BrowseRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseRecordingButtonPushed, true);
            app.BrowseRecordingButton.Text = 'BrowseRecording';
            app.OutputDirEditFieldLabel = uilabel(app.UIFigure);
            app.OutputDirEditFieldLabel.Text = 'OutputDir';
            app.OutputDirEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseOutputButton = uibutton(app.UIFigure, 'push');
            app.BrowseOutputButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseOutputButtonPushed, true);
            app.BrowseOutputButton.Text = 'BrowseOutput';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.BrowseTimestampButton = uibutton(app.UIFigure, 'push');
            app.BrowseTimestampButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseTimestampButtonPushed, true);
            app.BrowseTimestampButton.Text = 'BrowseTimestamp';
            app.TimeStampsDirEditFieldLabel = uilabel(app.UIFigure);
            app.TimeStampsDirEditFieldLabel.Text = 'TimeStampsDir';
            app.TimeStampsDirEditField = uieditfield(app.UIFigure, 'text');
            app.UsecustomspikecuttingCheckBox = uicheckbox(app.UIFigure);
            app.UsecustomspikecuttingCheckBox.ValueChangedFcn = createCallbackFcn(app, @UsecustomspikecuttingCheckBoxValueChanged, true);
            app.UsecustomspikecuttingCheckBox.Text = 'Use custom spike cutting';
            app.DictTable = uitable(app.UIFigure);
            app.DictTable.ColumnName = {'Number'; 'Directory of Dictionaries'};
            app.DictTable.RowName = {};
            app.DictTable.Visible = 'off';
            app.AddDirectoryButton = uibutton(app.UIFigure, 'push');
            app.AddDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @AddDirectoryButtonPushed, true);
            app.AddDirectoryButton.Visible = 'off';
            app.AddDirectoryButton.Text = 'Add Directory';
            app.SetDataDirectoriesLabel = uilabel(app.UIFigure);
            app.SetDataDirectoriesLabel.Text = 'Set Data Directories';
            app.SetConfigurationsLabel = uilabel(app.UIFigure);
            app.SetConfigurationsLabel.Text = 'Set Configurations';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Button = uibutton(app.Panel, 'push');
            app.RemoveDirectoryButton = uibutton(app.UIFigure, 'push');
            app.RemoveDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveDirectoryButtonPushed, true);
            app.RemoveDirectoryButton.Visible = 'off';
            app.RemoveDirectoryButton.Text = 'Remove Directory';
            app.ContinueFromCheckBox = uicheckbox(app.UIFigure);
            app.ContinueFromCheckBox.Tooltip = {'Check this box if you want to continue from a previous job that encountered an error.'};
            app.ContinueFromCheckBox.Text = 'Continue From';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end